classdef app_ver2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SerialPortReaderMLAppLabel  matlab.ui.control.Label
        BaudrateDropDown            matlab.ui.control.DropDown
        BaudrateDropDownLabel       matlab.ui.control.Label
        COMNameDropDown             matlab.ui.control.DropDown
        COMNameDropDownLabel        matlab.ui.control.Label
        COMMessageEditField         matlab.ui.control.EditField
        COMMessageEditFieldLabel    matlab.ui.control.Label
        EndAcquisitionButton        matlab.ui.control.Button
        StartAcquisitionButton      matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private) % avliable for every function
        aq_flag = false; % if stop aquisition happened
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)

            port = app.COMNameDropDown.Value; % com port name
            baud = str2double(app.BaudrateDropDown.Value); % baud rate

            % Open serial connection
            s = serialport(port, baud);
            pause(0.2); % wait for the garbage
            flush(s); % throw out the garbagde

            app.COMMessageEditField.Value = ['Logging data from ' num2str(port)];
            app.aq_flag = false;
            fs = 3200; % adxl345 sampling frequency
            while ~app.aq_flag
                try
                    % wait for header
                    hdr_bytes = read(s,6, "uint8"); % all header bytes at once
                    hdr = typecast(uint8(hdr_bytes(1:2)), 'uint16');
                    
                    if hdr ~= hex2dec("6969")
                        continue; % skip if not start of frame
                    end
                    msg_id = typecast(uint8(hdr_bytes(3:4)), 'uint16');
                    len = typecast(uint8(hdr_bytes(5:6)), 'uint16');
                    while s.NumBytesAvailable < len*4
                        % wait for full payload in the port
                    end
                    data = read(s,len,'single'); % read payload

                    switch msg_id
                        case 1  % FFT data 
                            N = (len-1)*2;  
                            f = (0:len-1) * (fs/N); % frequency domain vector
                           
                            plot(app.UIAxes, f, data, 'm');
                            title(app.UIAxes, 'Real-time FFT');
                            xlabel(app.UIAxes,'Frequency [Hz]');
                            ylabel(app.UIAxes,'Amplitude');
                            xlim(app.UIAxes,[0,fs/2]);
                            
                        case 2  % Time-domain data
                            t = (0:double(len)-1)/fs; % time domain

                            plot(app.UIAxes2, t, data, 'g');
                            title(app.UIAxes2, 'Time-domain signal');
                            xlabel(app.UIAxes2,'Time [s]');
                            ylabel(app.UIAxes2,'Amplitude');
                            ylim(app.UIAxes2,[-2,2]);
                            xlim(app.UIAxes2,[0,t(end)]);

                    end

                    drawnow;  % update plots

                catch ME
                    if strcmp(ME.identifier, 'MATLAB:class:InvalidHandle')
                        break; % serial port closed
                    elseif strcmp(ME.identifier, 'MATLAB:OperationTerminatedByUser')
                        disp('Logging stopped by user.');
                        break;
                    else
                        rethrow(ME);
                    end
                end
            end
            app.COMMessageEditField.Value = 'Logging ended';

            % close and clear - prepare for the next clean connection
            delete(s);
            clear s;


        end

        % Button pushed function: EndAcquisitionButton
        function EndAcquisitionButtonPushed(app, event)
            % end the loop as soon as possible
            app.aq_flag = true;

        end

        % Callback function
        function DetectdataflowrateCheckBoxValueChanged(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-time FFT - 256 samples')
            xlabel(app.UIAxes, 'Amplitude')
            ylabel(app.UIAxes, 'Frequency [Hz]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Data in time')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Acc [m/s^2]')
            zlabel(app.UIAxes2, 'Z')
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.EndAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.EndAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @EndAcquisitionButtonPushed, true);
            app.EndAcquisitionButton.Text = 'End Acquisition';
            app.COMMessageEditFieldLabel = uilabel(app.UIFigure);
            app.COMMessageEditFieldLabel.Text = 'COM Message';
            app.COMMessageEditField = uieditfield(app.UIFigure, 'text');
            app.COMNameDropDownLabel = uilabel(app.UIFigure);
            app.COMNameDropDownLabel.Text = 'COM Name';
            app.COMNameDropDown = uidropdown(app.UIFigure);
            app.COMNameDropDown.Items = {'COM4', 'COM5', 'COM6'};
            app.COMNameDropDown.Value = 'COM6';
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'9600', '115200', '921600', '460800', '57600 ', '230400', '57600'};
            app.BaudrateDropDown.Value = '115200';
            app.SerialPortReaderMLAppLabel = uilabel(app.UIFigure);
            app.SerialPortReaderMLAppLabel.Text = 'Serial Port Reader MLApp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ver2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end